classdef execute < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Toolbar       matlab.ui.container.Toolbar
        EasyChoice    matlab.ui.container.toolbar.PushTool
        MediumChoice  matlab.ui.container.toolbar.PushTool
        HardChoice    matlab.ui.container.toolbar.PushTool
    end

    
    properties (Access = private)
        gridRow % Total number of rows in game
        gridColumn % Total number of columns in game
        gridMine % Total number of mines in game
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIFigure, "visible", "off")
            movegui(app.UIFigure, "center")
            set(app.UIFigure, "visible", "on")
        end

        % Clicked callback: EasyChoice
        function EasyChoiceClicked(app, event)
            [app.gridRow, app.gridColumn, app.gridMine] = setDifficulty(1);
        end

        % Clicked callback: MediumChoice
        function MediumChoiceClicked(app, event)
            [app.gridRow, app.gridColumn, app.gridMine] = setDifficulty(2);
        end

        % Clicked callback: HardChoice
        function HardChoiceClicked(app, event)
            [app.gridRow, app.gridColumn, app.gridMine] = setDifficulty(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.EasyChoice = uipushtool(app.Toolbar);
            app.EasyChoice.Tooltip = {'Easy'};
            app.EasyChoice.ClickedCallback = createCallbackFcn(app, @EasyChoiceClicked, true);
            app.EasyChoice.Icon = fullfile(pathToMLAPP, 'img', 'easy.svg');
            app.MediumChoice = uipushtool(app.Toolbar);
            app.MediumChoice.Tooltip = {'Medium'};
            app.MediumChoice.ClickedCallback = createCallbackFcn(app, @MediumChoiceClicked, true);
            app.MediumChoice.Icon = fullfile(pathToMLAPP, 'img', 'medium.svg');
            app.HardChoice = uipushtool(app.Toolbar);
            app.HardChoice.Tooltip = {'Hard'};
            app.HardChoice.ClickedCallback = createCallbackFcn(app, @HardChoiceClicked, true);
            app.HardChoice.Icon = fullfile(pathToMLAPP, 'img', 'hard.svg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = execute

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end